classdef AtlasCIT168 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AtlasPanel                matlab.ui.container.Panel
        CheckBox_STN1             matlab.ui.control.CheckBox
        CheckBox_STN2             matlab.ui.control.CheckBox
        CheckBox_STN2motor        matlab.ui.control.CheckBox
        CheckBox_STN2associative  matlab.ui.control.CheckBox
        CheckBox_STN2limbic       matlab.ui.control.CheckBox
        CheckBox_RN2              matlab.ui.control.CheckBox
        CheckBox_VIM              matlab.ui.control.CheckBox
        CheckBox_VA               matlab.ui.control.CheckBox
        CheckBox_VOICM            matlab.ui.control.CheckBox
        CheckBox_Ca               matlab.ui.control.CheckBox
        CheckBox_Th               matlab.ui.control.CheckBox
        CheckBox_Pu               matlab.ui.control.CheckBox
        CheckBox_NAC              matlab.ui.control.CheckBox
        CheckBox_GPi1             matlab.ui.control.CheckBox
        CheckBox_GPi2             matlab.ui.control.CheckBox
        CheckBox_GPisensorimotor  matlab.ui.control.CheckBox
        CheckBox_GPisensory       matlab.ui.control.CheckBox
        CheckBox_GPiprimarymotor  matlab.ui.control.CheckBox
        CheckBox_GPipremotor      matlab.ui.control.CheckBox
        CheckBox_GPipostparietal  matlab.ui.control.CheckBox
        CheckBox_EXA              matlab.ui.control.CheckBox
        CheckBox_HN               matlab.ui.control.CheckBox
        CheckBox_HTH              matlab.ui.control.CheckBox
        CheckBox_SNr              matlab.ui.control.CheckBox
        CheckBox_GPe1             matlab.ui.control.CheckBox
        CheckBox_GPe2             matlab.ui.control.CheckBox
        CheckBox_SNc              matlab.ui.control.CheckBox
        CheckBox_RN1              matlab.ui.control.CheckBox
        CheckBox_GPioccipital     matlab.ui.control.CheckBox
        CheckBox_GPitemporal      matlab.ui.control.CheckBox
        CheckBox_GPiprefrontal    matlab.ui.control.CheckBox
        CheckBox_VTA              matlab.ui.control.CheckBox
        CheckBox_VPe              matlab.ui.control.CheckBox
        CheckBox_MN               matlab.ui.control.CheckBox
        CheckBox_PBP              matlab.ui.control.CheckBox
        CheckBox_ALL              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        struc_brain = zeros(35,1);
        struc_brain_name = [];

       % Description
%       % uiFigure = FastField
%        alt=[];
%       UIFigure = [];
      
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: CheckBox_STN1
        function CheckBox_STN1ValueChanged(app, event)
            if app.CheckBox_STN1.Value
                app.struc_brain(14)=1;
                app.struc_brain_name{14} = app.CheckBox_STN1.Text;   
                
            else
                app.struc_brain(14)=0;
            end
        end

        % Value changed function: CheckBox_EXA
        function CheckBox_EXAValueChanged(app, event)
            
            if app.CheckBox_EXA.Value
                app.struc_brain(2)=1;
                app.struc_brain_name{2} = app.CheckBox_EXA.Text;
            else
                app.struc_brain(2)=0;
            end
            
        end

        % Value changed function: CheckBox_STN2
        function CheckBox_STN2ValueChanged(app, event)
            if app.CheckBox_STN2.Value
                app.struc_brain(17)=1;
                app.struc_brain_name{17} = app.CheckBox_STN2.Text;
            else
                app.struc_brain(17)=0;
            end
        end

        % Value changed function: CheckBox_STN2motor
        function CheckBox_STN2motorValueChanged(app, event)
            if app.CheckBox_STN2motor.Value
                app.struc_brain(18)=1;
                app.struc_brain_name{18} = app.CheckBox_STN2motor.Text;
            else
                app.struc_brain(18)=0;
            end
            
        end

        % Value changed function: CheckBox_STN2associative
        function CheckBox_STN2associativeValueChanged(app, event)
            if app.CheckBox_STN2associative.Value
                app.struc_brain(19)=1;
                app.struc_brain_name{19} = app.CheckBox_STN2associative.Text;
            else
                app.struc_brain(19)=0;
            end
            
        end

        % Value changed function: CheckBox_STN2limbic
        function CheckBox_STN2limbicValueChanged(app, event)
            if app.CheckBox_STN2limbic.Value
                app.struc_brain(20)=1;
                app.struc_brain_name{20} = app.CheckBox_STN2limbic.Text;
            else
                app.struc_brain(20)=0;
            end
            
        end

        % Value changed function: CheckBox_RN1
        function CheckBox_RN1ValueChanged(app, event)
            if app.CheckBox_RN1.Value
                app.struc_brain(11)=1;
                app.struc_brain_name{11} = app.CheckBox_RN1.Text;
            else
                app.struc_brain(11)=0;
            end
            
        end

        % Value changed function: CheckBox_RN2
        function CheckBox_RN2ValueChanged(app, event)
            if app.CheckBox_RN2.Value
                app.struc_brain(30)=1;
                app.struc_brain_name{30} = app.CheckBox_RN2.Text;
            else
                app.struc_brain(30)=0;
            end
            
        end

        % Value changed function: CheckBox_MN
        function CheckBox_MNValueChanged(app, event)
            if app.CheckBox_MN.Value
                app.struc_brain(7)=1;
                app.struc_brain_name{7} = app.CheckBox_MN.Text;
            else
                app.struc_brain(7)=0;
            end
            
        end

        % Value changed function: CheckBox_PBP
        function CheckBox_PBPValueChanged(app, event)
            if app.CheckBox_PBP.Value
                app.struc_brain(9)=1;
                app.struc_brain_name{9} = app.CheckBox_PBP.Text;
            else
                app.struc_brain(9)=0;
            end
            
        end

        % Value changed function: CheckBox_VIM
        function CheckBox_VIMValueChanged(app, event)
            if app.CheckBox_VIM.Value
                app.struc_brain(34)=1;
                app.struc_brain_name{34} = app.CheckBox_VIM.Text;
            else
                app.struc_brain(34)=0;
            end
            
        end

        % Value changed function: CheckBox_VA
        function CheckBox_VAValueChanged(app, event)
            if app.CheckBox_VA.Value
                app.struc_brain(33)=1;
                app.struc_brain_name{33} = app.CheckBox_VA.Text;
            else
                app.struc_brain(33)=0;
            end
            
        end

        % Value changed function: CheckBox_VOICM
        function CheckBox_VOICMValueChanged(app, event)
            if app.CheckBox_VOICM.Value
                app.struc_brain(35)=1;
                app.struc_brain_name{35} = app.CheckBox_VOICM.Text;
            else
                app.struc_brain(35)=0;
            end
            
        end

        % Value changed function: CheckBox_Th
        function CheckBox_ThValueChanged(app, event)
            if app.CheckBox_Th.Value
                app.struc_brain(32)=1;
                app.struc_brain_name{32} = app.CheckBox_Th.Text;
            else
                app.struc_brain(32)=0;
            end
            
            
        end

        % Value changed function: CheckBox_Pu
        function CheckBox_PuValueChanged(app, event)
            if app.CheckBox_Pu.Value
                app.struc_brain(10)=1;
                app.struc_brain_name{10} = app.CheckBox_Pu.Text;
            else
                app.struc_brain(10)=0;
            end
            
        end

        % Value changed function: CheckBox_Ca
        function CheckBox_CaValueChanged(app, event)
            if app.CheckBox_Ca.Value
                app.struc_brain(1)=1;
                app.struc_brain_name{1} = app.CheckBox_Ca.Text;
            else
                app.struc_brain(1)=0;
            end
            
            
        end

        % Value changed function: CheckBox_NAC
        function CheckBox_NACValueChanged(app, event)
            if app.CheckBox_NAC.Value
                app.struc_brain(8)=1;
                app.struc_brain_name{8} = app.CheckBox_NAC.Text;
            else
                app.struc_brain(8)=0;
            end
            
            
        end

        % Value changed function: CheckBox_VTA
        function CheckBox_VTAValueChanged(app, event)
            if app.CheckBox_VTA.Value
                app.struc_brain(15)=1;
                app.struc_brain_name{15} = app.CheckBox_VTA.Text;
            else
                app.struc_brain(15)=0;
            end
            
            
        end

        % Value changed function: CheckBox_VPe
        function CheckBox_VPeValueChanged(app, event)
            if app.CheckBox_VPe.Value
                app.struc_brain(16)=1;
                app.struc_brain_name{16} = app.CheckBox_VPe.Text;
            else
                app.struc_brain(16)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPi1
        function CheckBox_GPi1ValueChanged(app, event)
            if app.CheckBox_GPi1.Value
                app.struc_brain(4)=1;
                app.struc_brain_name{4} = app.CheckBox_GPi1.Text;
            else
                app.struc_brain(4)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPi2
        function CheckBox_GPi2ValueChanged(app, event)
            if app.CheckBox_GPi2.Value
                app.struc_brain(21)=1;
                app.struc_brain_name{21} = app.CheckBox_GPi2.Text;
            else
                app.struc_brain(21)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPisensorimotor
        function CheckBox_GPisensorimotorValueChanged(app, event)
            if app.CheckBox_GPisensorimotor.Value
                app.struc_brain(22)=1;
                app.struc_brain_name{22} = app.CheckBox_GPisensorimotor.Text;
            else
                app.struc_brain(22)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPiprimarymotor
        function CheckBox_GPiprimarymotorValueChanged(app, event)
            if app.CheckBox_GPiprimarymotor.Value
                app.struc_brain(23)=1;
                app.struc_brain_name{23} = app.CheckBox_GPiprimarymotor.Text;
            else
                app.struc_brain(23)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPipremotor
        function CheckBox_GPipremotorValueChanged(app, event)
            if app.CheckBox_GPipremotor.Value
                app.struc_brain(24)=1;
                app.struc_brain_name{24} = app.CheckBox_GPipremotor.Text;
            else
                app.struc_brain(24)=0;
            end
            
        end

        % Value changed function: CheckBox_GPisensory
        function CheckBox_GPisensoryValueChanged(app, event)
             if app.CheckBox_Gpisensory.Value
                app.struc_brain(25)=1;
                app.struc_brain_name{25} = app.CheckBox_GPisensory.Text;
            else
                app.struc_brain(25)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPipostparietal
        function CheckBox_GPipostparietalValueChanged(app, event)
            if app.CheckBox_GPipostparietal.Value
                app.struc_brain(26)=1;
                app.struc_brain_name{26} = app.CheckBox_GPipostparietal.Text;
            else
                app.struc_brain(26)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPioccipital
        function CheckBox_GPioccipitalValueChanged(app, event)
            if app.CheckBox_GPioccipital.Value
                app.struc_brain(27)=1;
                app.struc_brain_name{27} = app.uiFigSelect.CheckBox_GPioccipital.Text;
            else
                app.struc_brain(27)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPitemporal
        function CheckBox_GPitemporalValueChanged(app, event)
            if app.CheckBox_GPitemporal.Value
                app.struc_brain(28)=1;
                app.struc_brain_name{28} = app.CheckBox_GPitemporal.Text;
            else
                app.struc_brain(28)=0;
            end
            
            
        end

        % Value changed function: CheckBox_HN
        function CheckBox_HNValueChanged(app, event)
            if app.CheckBox_HN.Value
                app.struc_brain(5)=1;
                app.struc_brain_name{5} = app.CheckBox_HN.Text;
            else
                app.struc_brain(5)=0;
            end
            
            
        end

        % Value changed function: CheckBox_HTH
        function CheckBox_HTHValueChanged(app, event)
            if app.CheckBox_HTH.Value
                app.struc_brain(6)=1;
                app.struc_brain_name{6} = app.CheckBox_HTH.Text;
            else
                app.struc_brain(6)=0;
            end
            
        end

        % Value changed function: CheckBox_GPe1
        function CheckBox_GPe1ValueChanged(app, event)
            if app.CheckBox_GPe1.Value
                app.struc_brain(3)=1;
                app.struc_brain_name{3} = app.CheckBox_GPe1.Text;
            else
                app.struc_brain(3)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPe2
        function CheckBox_GPe2ValueChanged(app, event)
            if app.CheckBox_GPe2.Value
                app.struc_brain(31)=1;
                app.struc_brain_name{31} = app.uiFigSelect.CheckBox_GPe2.Text;
            else
                app.struc_brain(31)=0;
            end
            
        end

        % Value changed function: CheckBox_SNr
        function CheckBox_SNrValueChanged(app, event)
            if app.CheckBox_SNr.Value
                app.struc_brain(13)=1;
                app.struc_brain_name{13} = app.CheckBox_SNr.Text;
            else
                app.struc_brain(13)=0;
            end
            
            
        end

        % Value changed function: CheckBox_SNc
        function CheckBox_SNcValueChanged(app, event)
            if app.CheckBox_SNc.Value
                app.struc_brain(12)=1;
                app.struc_brain_name{12} = app.CheckBox_SNc.Text;
            else
                app.struc_brain(12)=0;
            end
            
            
        end

        % Value changed function: CheckBox_GPiprefrontal
        function CheckBox_GPiprefrontalValueChanged(app, event)
            if app.CheckBox_GPiprefrontal.Value
                app.struc_brain(29)=1;
                app.struc_brain_name{29} = app.CheckBox_GPiprefrontal.Text;
            else
                app.struc_brain(29)=0;
            end
            
            
        end

        % Value changed function: CheckBox_ALL
        function CheckBox_ALLValueChanged(app, event)
            if app.CheckBox_ALL.Value
                app.struc_brain = ones(35,1);
                app.struc_brain_name{35} = app.CheckBox_VOICM.Text;
                app.struc_brain_name{34} = app.CheckBox_VIM.Text;
                app.struc_brain_name{33} = app.CheckBox_VA.Text;
                app.struc_brain_name{32} = app.CheckBox_Th.Text;
                app.struc_brain_name{31} = app.CheckBox_GPe2.Text;
                app.struc_brain_name{30} = app.CheckBox_RN2.Text;
                app.struc_brain_name{29} = app.CheckBox_GPiprefrontal.Text;
                app.struc_brain_name{28} = app.CheckBox_GPitemporal.Text;
                app.struc_brain_name{27} = app.CheckBox_GPioccipital.Text;
                app.struc_brain_name{26} = app.CheckBox_GPipostparietal.Text;
                app.struc_brain_name{25} = app.CheckBox_GPisensory.Text;
                app.struc_brain_name{24} = app.CheckBox_GPipremotor.Text;
                app.struc_brain_name{23} = app.CheckBox_GPiprimarymotor.Text;
                app.struc_brain_name{22} = app.CheckBox_GPisensorimotor.Text;
                app.struc_brain_name{21} = app.CheckBox_GPi2.Text;
                app.struc_brain_name{20} = app.CheckBox_STN2limbic.Text;
                app.struc_brain_name{19} = app.CheckBox_STN2associative.Text;
                app.struc_brain_name{18} = app.CheckBox_STN2motor.Text;
                app.struc_brain_name{17} = app.CheckBox_STN2.Text;
                app.struc_brain_name{16} = app.CheckBox_VPe.Text;
                app.struc_brain_name{15} = app.CheckBox_VTA.Text;
                app.struc_brain_name{14} = app.CheckBox_STN1.Text;
                app.struc_brain_name{13} = app.CheckBox_SNr.Text;
                app.struc_brain_name{12} = app.CheckBox_SNc.Text;
                app.struc_brain_name{11} = app.CheckBox_RN1.Text;
                app.struc_brain_name{10} = app.CheckBox_Pu.Text;
                app.struc_brain_name{9} = app.CheckBox_PBP.Text;
                app.struc_brain_name{8} = app.CheckBox_NAC.Text;
                app.struc_brain_name{7} = app.CheckBox_MN.Text;
                app.struc_brain_name{6} = app.CheckBox_HTH.Text;
                app.struc_brain_name{5} = app.CheckBox_HN.Text;
                app.struc_brain_name{4} = app.CheckBox_GPi1.Text;
                app.struc_brain_name{3} = app.CheckBox_GPe1.Text;
                app.struc_brain_name{2} = app.CheckBox_EXA.Text;
                app.struc_brain_name{1} = app.CheckBox_Ca.Text;
                
                app.CheckBox_Ca.Value = 1;
                app.CheckBox_EXA.Value = 1;
                app.CheckBox_GPe1.Value = 1;
                app.CheckBox_GPi1.Value = 1;
                app.CheckBox_HN.Value = 1;
                app.CheckBox_HTH.Value = 1;
                app.CheckBox_MN.Value = 1;
                app.CheckBox_NAC.Value = 1;
                app.CheckBox_PBP.Value = 1;
                app.CheckBox_Pu.Value = 1;
                app.CheckBox_RN1.Value = 1;
                app.CheckBox_SNc.Value = 1;
                app.CheckBox_SNr.Value = 1;
                app.CheckBox_STN1.Value = 1;
                app.CheckBox_VTA.Value = 1;
                app.CheckBox_VPe.Value = 1;
                app.CheckBox_STN2.Value = 1;
                app.CheckBox_STN2motor.Value = 1;
                app.CheckBox_STN2associative.Value = 1;
                app.CheckBox_STN2limbic.Value = 1;
                app.CheckBox_GPi2.Value = 1;
                app.CheckBox_GPisensorimotor.Value = 1;
                app.CheckBox_GPiprimarymotor.Value = 1;
                app.CheckBox_GPipremotor.Value = 1;
                app.CheckBox_GPisensory.Value = 1;
                app.CheckBox_GPipostparietal.Value = 1;
                app.CheckBox_GPioccipital.Value = 1;
                app.CheckBox_GPitemporal.Value = 1;
                app.CheckBox_GPiprefrontal.Value = 1;
                app.CheckBox_RN2.Value = 1;
                app.CheckBox_GPe2.Value = 1;
                app.CheckBox_Th.Value = 1;
                app.CheckBox_VA.Value = 1;
                app.CheckBox_VIM.Value = 1;
                app.CheckBox_VOICM.Value = 1;
               
            else
                app.struc_brain = zeros(35,1);
                app.CheckBox_Ca.Value = 0;
                app.CheckBox_EXA.Value = 0;
                app.CheckBox_GPe1.Value = 0;
                app.CheckBox_GPi1.Value = 0;
                app.CheckBox_HN.Value = 0;
                app.CheckBox_HTH.Value = 0;
                app.CheckBox_MN.Value = 0;
                app.CheckBox_NAC.Value = 0;
                app.CheckBox_PBP.Value = 0;
                app.CheckBox_Pu.Value = 0;
                app.CheckBox_RN1.Value = 0;
                app.CheckBox_SNc.Value = 0;
                app.CheckBox_SNr.Value = 0;
                app.CheckBox_STN1.Value = 0;
                app.CheckBox_VTA.Value = 0;
                app.CheckBox_VPe.Value = 0;
                app.CheckBox_STN2.Value = 0;
                app.CheckBox_STN2motor.Value = 0;
                app.CheckBox_STN2associative.Value = 0;
                app.CheckBox_STN2limbic.Value = 0;
                app.CheckBox_GPi2.Value = 0;
                app.CheckBox_GPisensorimotor.Value = 0;
                app.CheckBox_GPiprimarymotor.Value = 0;
                app.CheckBox_GPipremotor.Value = 0;
                app.CheckBox_GPisensory.Value = 0;
                app.CheckBox_GPipostparietal.Value = 0;
                app.CheckBox_GPioccipital.Value = 0;
                app.CheckBox_GPitemporal.Value = 0;
                app.CheckBox_GPiprefrontal.Value = 0;
                app.CheckBox_RN2.Value = 0;
                app.CheckBox_GPe2.Value = 0;
                app.CheckBox_Th.Value = 0;
                app.CheckBox_VA.Value = 0;
                app.CheckBox_VIM.Value = 0;
                app.CheckBox_VOICM.Value = 0;
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AtlasPanel = uipanel(app.UIFigure);
            app.AtlasPanel.Title = 'Atlas';
            app.CheckBox_STN1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN1ValueChanged, true);
            app.CheckBox_STN1.Text = 'STN1';
            app.CheckBox_STN2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2ValueChanged, true);
            app.CheckBox_STN2.Text = 'STN2';
            app.CheckBox_STN2motor = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2motor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2motorValueChanged, true);
            app.CheckBox_STN2motor.Text = 'STN2 motor';
            app.CheckBox_STN2associative = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2associative.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2associativeValueChanged, true);
            app.CheckBox_STN2associative.Text = 'STN2 associative';
            app.CheckBox_STN2limbic = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2limbic.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2limbicValueChanged, true);
            app.CheckBox_STN2limbic.Text = 'STN2 limbic';
            app.CheckBox_RN2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_RN2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RN2ValueChanged, true);
            app.CheckBox_RN2.Text = 'RN2';
            app.CheckBox_VIM = uicheckbox(app.AtlasPanel);
            app.CheckBox_VIM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VIMValueChanged, true);
            app.CheckBox_VIM.Text = 'VIM';
            app.CheckBox_VA = uicheckbox(app.AtlasPanel);
            app.CheckBox_VA.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VAValueChanged, true);
            app.CheckBox_VA.Text = 'VA';
            app.CheckBox_VOICM = uicheckbox(app.AtlasPanel);
            app.CheckBox_VOICM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VOICMValueChanged, true);
            app.CheckBox_VOICM.Text = 'VOI/CM';
            app.CheckBox_Ca = uicheckbox(app.AtlasPanel);
            app.CheckBox_Ca.ValueChangedFcn = createCallbackFcn(app, @CheckBox_CaValueChanged, true);
            app.CheckBox_Ca.Text = 'Ca';
            app.CheckBox_Th = uicheckbox(app.AtlasPanel);
            app.CheckBox_Th.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ThValueChanged, true);
            app.CheckBox_Th.Text = 'Th';
            app.CheckBox_Pu = uicheckbox(app.AtlasPanel);
            app.CheckBox_Pu.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PuValueChanged, true);
            app.CheckBox_Pu.Text = 'Pu';
            app.CheckBox_NAC = uicheckbox(app.AtlasPanel);
            app.CheckBox_NAC.ValueChangedFcn = createCallbackFcn(app, @CheckBox_NACValueChanged, true);
            app.CheckBox_NAC.Text = 'NAC';
            app.CheckBox_GPi1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPi1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPi1ValueChanged, true);
            app.CheckBox_GPi1.Text = 'GPi1';
            app.CheckBox_GPi2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPi2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPi2ValueChanged, true);
            app.CheckBox_GPi2.Text = 'GPi2';
            app.CheckBox_GPisensorimotor = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPisensorimotor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPisensorimotorValueChanged, true);
            app.CheckBox_GPisensorimotor.Text = 'GPi sensorimotor';
            app.CheckBox_GPisensory = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPisensory.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPisensoryValueChanged, true);
            app.CheckBox_GPisensory.Text = 'GPi sensory';
            app.CheckBox_GPiprimarymotor = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPiprimarymotor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPiprimarymotorValueChanged, true);
            app.CheckBox_GPiprimarymotor.Text = 'GPi primarymotor';
            app.CheckBox_GPipremotor = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPipremotor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPipremotorValueChanged, true);
            app.CheckBox_GPipremotor.Text = 'GPi premotor';
            app.CheckBox_GPipostparietal = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPipostparietal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPipostparietalValueChanged, true);
            app.CheckBox_GPipostparietal.Text = 'GPi postparietal';
            app.CheckBox_EXA = uicheckbox(app.AtlasPanel);
            app.CheckBox_EXA.ValueChangedFcn = createCallbackFcn(app, @CheckBox_EXAValueChanged, true);
            app.CheckBox_EXA.Text = 'EXA';
            app.CheckBox_HN = uicheckbox(app.AtlasPanel);
            app.CheckBox_HN.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HNValueChanged, true);
            app.CheckBox_HN.Text = 'HN';
            app.CheckBox_HTH = uicheckbox(app.AtlasPanel);
            app.CheckBox_HTH.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HTHValueChanged, true);
            app.CheckBox_HTH.Text = 'HTH';
            app.CheckBox_SNr = uicheckbox(app.AtlasPanel);
            app.CheckBox_SNr.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SNrValueChanged, true);
            app.CheckBox_SNr.Text = 'SNr';
            app.CheckBox_GPe1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPe1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPe1ValueChanged, true);
            app.CheckBox_GPe1.Text = 'GPe1';
            app.CheckBox_GPe2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPe2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPe2ValueChanged, true);
            app.CheckBox_GPe2.Text = 'GPe2';
            app.CheckBox_SNc = uicheckbox(app.AtlasPanel);
            app.CheckBox_SNc.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SNcValueChanged, true);
            app.CheckBox_SNc.Text = 'SNc';
            app.CheckBox_RN1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_RN1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RN1ValueChanged, true);
            app.CheckBox_RN1.Text = 'RN1';
            app.CheckBox_GPioccipital = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPioccipital.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPioccipitalValueChanged, true);
            app.CheckBox_GPioccipital.Text = 'GPi occipital';
            app.CheckBox_GPitemporal = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPitemporal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPitemporalValueChanged, true);
            app.CheckBox_GPitemporal.Text = 'GPi temporal';
            app.CheckBox_GPiprefrontal = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPiprefrontal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPiprefrontalValueChanged, true);
            app.CheckBox_GPiprefrontal.Text = 'GPi prefrontal';
            app.CheckBox_VTA = uicheckbox(app.AtlasPanel);
            app.CheckBox_VTA.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VTAValueChanged, true);
            app.CheckBox_VTA.Text = 'VTA';
            app.CheckBox_VPe = uicheckbox(app.AtlasPanel);
            app.CheckBox_VPe.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VPeValueChanged, true);
            app.CheckBox_VPe.Text = 'VPe';
            app.CheckBox_MN = uicheckbox(app.AtlasPanel);
            app.CheckBox_MN.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MNValueChanged, true);
            app.CheckBox_MN.Text = 'MN';
            app.CheckBox_PBP = uicheckbox(app.AtlasPanel);
            app.CheckBox_PBP.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PBPValueChanged, true);
            app.CheckBox_PBP.Text = 'PBP';
            app.CheckBox_ALL = uicheckbox(app.AtlasPanel);
            app.CheckBox_ALL.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ALLValueChanged, true);
            app.CheckBox_ALL.Text = 'ALL';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AtlasCIT168

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end