classdef FastField < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FastFieldUIFigure          matlab.ui.Figure
        GridLayout3                matlab.ui.container.GridLayout
        ViewPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        axes_result                matlab.ui.control.UIAxes
        Panel_4                    matlab.ui.container.Panel
        LightSwitchLabel           matlab.ui.control.Label
        LightSwitch                matlab.ui.control.Switch
        LightSwitchLabel_2         matlab.ui.control.Label
        atlas_button               matlab.ui.control.Button
        MessagePanel               matlab.ui.container.Panel
        error_box                  matlab.ui.control.EditField
        ControlPanel               matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        ParametersPanel            matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        amp_box                    matlab.ui.control.NumericEditField
        cond_box                   matlab.ui.control.NumericEditField
        SideDropDownLabel_3        matlab.ui.control.Label
        btnApproximateFromPW       matlab.ui.control.Button
        inputEFieldThreshold       matlab.ui.control.NumericEditField
        ElectrodedesignPanel       matlab.ui.container.Panel
        axes_el                    matlab.ui.control.UIAxes
        ContacSPanel               matlab.ui.container.Panel
        con8_per                   matlab.ui.control.Label
        Contact8Label              matlab.ui.control.Label
        con8                       matlab.ui.control.NumericEditField
        Contact7Label              matlab.ui.control.Label
        con7                       matlab.ui.control.NumericEditField
        Contact6Label              matlab.ui.control.Label
        con6                       matlab.ui.control.NumericEditField
        Contact5Label              matlab.ui.control.Label
        con5                       matlab.ui.control.NumericEditField
        Contact4Label              matlab.ui.control.Label
        con4                       matlab.ui.control.NumericEditField
        Contact3Label              matlab.ui.control.Label
        con3                       matlab.ui.control.NumericEditField
        Contact2Label              matlab.ui.control.Label
        con2                       matlab.ui.control.NumericEditField
        Contact1Label              matlab.ui.control.Label
        con1                       matlab.ui.control.NumericEditField
        con7_per                   matlab.ui.control.Label
        con6_per                   matlab.ui.control.Label
        con5_per                   matlab.ui.control.Label
        con4_per                   matlab.ui.control.Label
        con3_per                   matlab.ui.control.Label
        con2_per                   matlab.ui.control.Label
        con1_per                   matlab.ui.control.Label
        AmplitudemASliderLabel     matlab.ui.control.Label
        amp_slider                 matlab.ui.control.Slider
        ConductivitySmSliderLabel  matlab.ui.control.Label
        cond_slider                matlab.ui.control.Slider
        ElectrodeTypeLabel         matlab.ui.control.Label
        electrode_type             matlab.ui.control.DropDown
        SideDropDownLabel_5        matlab.ui.control.Label
        amp_mode                   matlab.ui.control.Switch
        PersonalisetopatientsanatomyPanel  matlab.ui.container.Panel
        patient_path               matlab.ui.control.Button
        stim_name                  matlab.ui.control.EditField
        ButtonGroup                matlab.ui.container.ButtonGroup
        right_side                 matlab.ui.control.RadioButton
        left_side                  matlab.ui.control.RadioButton
        SideDropDownLabel_2        matlab.ui.control.Label
        SideDropDownLabel_4        matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        ele = [];
        el_value=[];
        std_ef = [];
        grid_v = [];
        el_model = [];
        el_pat = [];
        atl = [];
        error_text = [];
        efield_handle = [];
        xg_handle = [];
        yg_handle = [];
        zg_handle = [];
        trans_mat_hanlde = [];
        elfv_handle = [];
        perc_handle = [];
        side_handle = [];
        struc_brain_name1 = [];
        struc_brain1 = zeros(36,1);
        s1 = [];
        elec_plo = [];
        stimulation1 = [];
        hTarget = NaN(35,1);
        
        uiFigSelect = [];
        camlightLeft = []; % Handle to camlight left
        camlightRight = []; % Handle to camlight left
        camlightHeadlight= []; % Handle to camlight left
        fastfield_folder = [];
        patient_folder = [];
        aview=0;
        bview=180;
        impedence=950;


        eFieldThreshold = 0.2;  % [V/mm] the threshold for the VTA, do be get by approximaiton gui later
    end
    
    methods (Access = public)
        
        
        function model_ff(app)
            
            amp = app.amp_box.Value;
            conductivity = app.cond_slider.Value;
            if app.right_side.Value
                side = 1;
            elseif app.left_side.Value
                side = 2;
            end
            app.side_handle = side;
            p1 = app.con1.Value;
            p2 = app.con2.Value;
            p3 = app.con3.Value;
            p4 = app.con4.Value;
            p5 = app.con5.Value;
            p6 = app.con6.Value;
            p7 = app.con7.Value;
            p8 = app.con8.Value;
            perc = [p1,p2,p3,p4,p5,p6,p7,p8];
            app.perc_handle = perc;
            standard_efield = app.std_ef;
            grid_vec = app.grid_v;
            electrode = app.el_model;
            electrode_patient = app.el_pat;
           
            
            if sum(perc) < 100.9 && sum(perc) > 99.1  || sum(perc)==0
                [Efield,xg,yg,zg,elfv,trans_mat] = fastfield_main(standard_efield,grid_vec,electrode,electrode_patient,perc,amp,side,conductivity,app.amp_mode.Value,app.impedence);
                app.error_box.Value = '';
                app.efield_handle = Efield;
                app.elfv_handle = elfv;
                app.xg_handle = xg;
                app.yg_handle = yg;
                app.zg_handle = zg;
                app.trans_mat_hanlde = trans_mat;
                
            else
                app.error_box.Value = 'Sum of the contacts should be equal to 100';
                app.error_box.Editable = 'off';
                app.error_box.FontColor = [0.9 0.2 0.2];
                
            end
            
        end
        
        function visualize_ff(app)
            %cla(app.axes_result)
            
            elfv = app.elfv_handle;
            electrode = app.el_model;
            perc = app.perc_handle;
            Efield = app.efield_handle;
            xg = app.xg_handle;
            yg = app.yg_handle;
            zg = app.zg_handle;
            elec_plo1 = app.elec_plo;
            
            perc(9)=0;


            if ~isempty(elec_plo1)
                delete(elec_plo1)
                elec_plo1=[];
            end
            
            
            for i=1:length(elfv)
                
                if (ismember(i,1:length(electrode.contacts)))
                    
                    if perc(i)>0
                        elec_plo1(i) = patch(elfv(i), 'FaceColor', [0.9 0.7 0.1] , 'EdgeColor', 'none','parent', app.axes_result);
                        
                    else
                        elec_plo1(i) = patch(elfv(i), 'FaceColor', rgb('dimgray'), 'EdgeColor', 'none','parent', app.axes_result);
                        
                    end
                    
                elseif (ismember(i, length(electrode.contacts)+1:length(electrode.contacts)+length(electrode.insulation)))
                    elec_plo1(i) = patch(elfv(i), 'FaceColor',rgb('lightgray'), 'EdgeColor', 'none','parent', app.axes_result);
                    
                end
            end
            
            % app.elec_plo = elec_plo1;
            
            rotate3d(app.axes_result,'on')
            app.axes_result.BackgroundColor = [0 0 0];
            app.axes_result.XTick = [];
            app.axes_result.YTick = [];
            app.axes_result.ZTick = [];
            app.axes_result.XColor = 'none';
            app.axes_result.YColor = 'none';
            app.axes_result.ZColor = 'none';
            app.axes_result.Title.String = 'Electric Field';
            app.axes_result.Color = [0 0 0];
            app.axes_result.CameraViewAngleMode = 'manual';
            app.axes_result.CameraViewAngle = 15;
            app.axes_result.DataAspectRatioMode ='manual' ;
            app.axes_result.DataAspectRatio =[1 1 1];
            %app.axes_result.View = [12 -24];
            %app.axes_result.View = [-21 9];
            %app.axes_result.View = [42 -68];
            %app.axes_result.View = [3 25];
            axis(app.axes_result, 'fill');
            app.axes_result.Clipping = 'off';
            %app.axes_result.Toolbar.Visible = 'off';
            %zoom(app.axes_result,'on');
            
            app.axes_result.XLim = [-inf inf];
            app.axes_result.YLim = [-inf inf];
            app.axes_result.ZLim = [-inf inf];
            
            app.elec_plo = elec_plo1;
            
            %  app.LightSwitchValueChanged([]);
            
            lg = length(xg);
            cut = 1:lg;
            
            ef = app.s1;
            if ~isempty(ef)
                delete(ef)
                ef = [];
            end
            
            data = Efield(:,:,cut);
            X=xg;
            Y=yg;
            Z=zg;
            
            hold(app.axes_result,'on');
            s = isosurface(X(:,:,cut), Y(:,:,cut), Z(:,:,cut), data > (app.eFieldThreshold * 1000) );
            
            ef = patch(s, 'EdgeColor', 'none', 'FaceColor', [1 0 0] , 'FaceAlpha', 0.4, 'Parent', app.axes_result);
            app.s1 = ef;
            LightSwitchValueChanged(app);
            
        end
        
        function plot_el_design(app)
            
            dir_fastfield = app.fastfield_folder;
            new_el_value = num2str(app.electrode_type.Value);
            
            app.ele = load([dir_fastfield filesep 'resources' filesep new_el_value filesep new_el_value '_elfv1.mat']);
            elfv1 = app.ele.elfv1;
            l_elfv1 = length(elfv1);
            
            if l_elfv1 < 5
                app.con5.Enable = 'off';
                app.con6.Enable = 'off';
                app.con7.Enable = 'off';
                app.con8.Enable = 'off';
                
                app.con5.Value = 0;
                app.con6.Value = 0;
                app.con7.Value = 0;
                app.con8.Value = 0;
                
                app.con5.FontColor = [0.1 0.1 0.1];
                app.Contact5Label.FontColor = [0.1 0.1 0.1];
                app.con5_per.FontColor = [0.1 0.1 0.1];
                
                app.con6.FontColor = [0.1 0.1 0.1];
                app.Contact6Label.FontColor = [0.1 0.1 0.1];
                app.con6_per.FontColor = [0.1 0.1 0.1];
                
                app.con7.FontColor = [0.1 0.1 0.1];
                app.Contact7Label.FontColor = [0.1 0.1 0.1];
                app.con7_per.FontColor = [0.1 0.1 0.1];
                
                
                app.con8.FontColor = [0.1 0.1 0.1];
                app.Contact8Label.FontColor = [0.1 0.1 0.1];
                app.con8_per.FontColor = [0.1 0.1 0.1];
            else
                app.con5.Enable = 'on';
                app.con6.Enable = 'on';
                app.con7.Enable = 'on';
                app.con8.Enable = 'on';
            end
            
            cla(app.axes_el)
            
            elfv1=app.ele.elfv1;
            
            for i=1:length(elfv1)
                
                patch(elfv1(i), 'FaceColor', rgb('dimgray'), 'EdgeColor','none', 'FaceAlpha', 0.3,'parent', app.axes_el)
                
                pointt=mean(elfv1(i).vertices);
                if i<9
                    t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(i),'parent', app.axes_el);
                    t1.Color=[0 0 0];
                    t1.FontSize=18;
                    t1.FontWeight = 'Bold';
                    
                elseif i==9
                    
                    t1=text(pointt(1)+0.2,pointt(2),pointt(3),'M','parent', app.axes_el);
                    t1.Color=[0 0 0];
                    t1.FontSize=18;
                    t1.FontWeight = 'Bold';
                end
                
            end
            
            %app.axes_el.View = [40 180];
            app.axes_el.CameraPositionMode = 'manual';
            app.axes_el.CameraPosition = [0 60 60];
            app.axes_el.DataAspectRatioMode ='manual' ;
            app.axes_el.DataAspectRatio =[1 1 1];
            app.axes_el.CameraViewAngleMode = 'manual';
            app.axes_el.Toolbar.Visible = 'off';
            
            if strcmp(new_el_value, 'boston_vercise') || strcmp(new_el_value, 'medtronic_3391') || strcmp(new_el_value, 'pins_l303')
                app.axes_el.CameraViewAngle = 9;
            else
                app.axes_el.CameraViewAngle = 5;
            end
            
            % app.camlightLeft = camlight(app.axes_el, 'left');
            % app.camlightRight = camlight(app.axes_el, 'right');
            % app.camlightHeadlight = camlight(app.axes_el, 'headlight');
            % lighting(app.axes_el,'gouraud');
            
        end


        function  show_atlas(app,tar)
            % tar = app.struc_brain_name1{kk};
            
            if isempty(app.atl)
                app.atl = load([dir_fastfield filesep 'resources' filesep 'atlas_combined.mat']);
            end
            atlases = app.atl;
            side = app.side_handle;
            
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            
            if side == 1
                color_st = atlases.colormap(Ind,:);
                app.hTarget(Ind) =  patch(atlases.fv{Ind,1}, 'FaceColor', color_st, 'EdgeColor', 'none', 'FaceAlpha', 0.35,'parent', app.axes_result);
                
            elseif side == 2
                color_st = atlases.colormap(Ind,:);
                app.hTarget(Ind) = patch(atlases.fv{Ind,2}, 'FaceColor', color_st, 'EdgeColor', 'none', 'FaceAlpha', 0.35,'parent', app.axes_result);
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
            app.fastfield_folder = fileparts(mfilename('fullpath'));
            
            
            dir_fastfield = app.fastfield_folder;
            Electrode_type = app.electrode_type.Value;
            dir_patient = [dir_fastfield filesep 'resources' filesep Electrode_type];
            app.patient_folder = dir_patient;
            reco = load([dir_patient filesep 'ea_reconstruction.mat']);
            electrode_patient = reco.reco.mni;
            app.el_pat = electrode_patient;
            app.electrode_typeValueChanged([]);
            app.axes_result.View = [180 0];
            
        end

        % Button pushed function: patient_path
        function patient_pathButtonPushed(app, event)
            path_pat = uigetdir();
            %path_pat= '/Users/mehri.baniasadi/Desktop/SelectedPatients/BOSTON/96376/DistalPacer96376/output/dbsar2leaddbs';
            dir_patient = path_pat;
            app.patient_path.Text = path_pat ;
            app.patient_folder = path_pat;
            
            reco = load([dir_patient filesep 'ea_reconstruction.mat']);
            electrode_patient = reco.reco.mni;
            app.el_pat = electrode_patient;
            %app.electrode_typeValueChanged([]); % update electrode view
            electrode_typeValueChanged(app)
            model_ff(app);
            visualize_ff(app);

            
        end

        % Value changed function: electrode_type
        function electrode_typeValueChanged(app, event)
            
            app.error_box.Value = '';
            dir_patient = app.patient_folder;
            dir_fastfield = app.fastfield_folder;
            new_el_value = app.electrode_type.Value;
            old_el_value = app.el_value;
            Electrode_type = new_el_value;
            
            if strcmp(new_el_value, old_el_value)
                if isempty(app.std_ef)
                    [standard_efield,grid_vec,electrode,electrode_patient,atlases] = load_files(dir_fastfield,dir_patient,Electrode_type);
                    app.std_ef = standard_efield;
                    app.grid_v = grid_vec;
                    app.el_model = electrode;
                    app.el_pat = electrode_patient;
                    app.atl = atlases;
                end
                
            else
                
                app.el_value = new_el_value;
                if strcmp(app.patient_path.Text,'Choose Leadbs Patient Directory')
                    dir_patient = [dir_fastfield filesep 'resources' filesep app.el_value];
                    app.patient_folder = dir_patient;
                end
                [standard_efield,grid_vec,electrode,electrode_patient,atlases] = load_files(dir_fastfield,dir_patient,Electrode_type);
                app.std_ef = standard_efield;
                app.grid_v = grid_vec;
                app.el_model = electrode;
                app.el_pat = electrode_patient;
                app.atl = atlases;
                
                plot_el_design(app)
                model_ff(app)
                visualize_ff(app)
            end
            %             end
            
        end

        % Value changed function: con8
        function con8ValueChanged(app, event)
            value = app.con8.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(8).vertices);
            
            if value > 0
                app.Contact8Label.FontColor = [0.1 0.55 0.6];
                app.con8.FontColor = [0.1 0.55 0.6];
                app.con8_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(8),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con8.FontColor = [0.1 0.1 0.1];
                app.Contact8Label.FontColor = [0.1 0.1 0.1];
                app.con8_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(8),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: con7
        function con7ValueChanged(app, event)
            value = app.con7.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(7).vertices);
            
            if value > 0
                app.Contact7Label.FontColor = [0.1 0.55 0.6];
                app.con7.FontColor = [0.1 0.55 0.6];
                app.con7_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(7),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con7.FontColor = [0.1 0.1 0.1];
                app.Contact7Label.FontColor = [0.1 0.1 0.1];
                app.con7_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(7),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: con6
        function con6ValueChanged(app, event)
            value = app.con6.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(6).vertices);
            
            if value > 0
                app.Contact6Label.FontColor = [0.1 0.55 0.6];
                app.con6.FontColor = [0.1 0.55 0.6];
                app.con6_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(6),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con6.FontColor = [0.1 0.1 0.1];
                app.Contact6Label.FontColor = [0.1 0.1 0.1];
                app.con6_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(6),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: con5
        function con5ValueChanged(app, event)
            value = app.con5.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(5).vertices);
            
            if value > 0
                app.Contact5Label.FontColor = [0.1 0.55 0.6];
                app.con5.FontColor = [0.1 0.55 0.6];
                app.con5_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(5),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con5.FontColor = [0.1 0.1 0.1];
                app.Contact5Label.FontColor = [0.1 0.1 0.1];
                app.con5_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(5),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: con4
        function con4ValueChanged(app, event)
            value = app.con4.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(4).vertices);
            
            if value > 0
                app.Contact4Label.FontColor = [0.1 0.55 0.6];
                app.con4.FontColor = [0.1 0.55 0.6];
                app.con4_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(4),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con4.FontColor = [0.1 0.1 0.1];
                app.Contact4Label.FontColor = [0.1 0.1 0.1];
                app.con4_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(4),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: con3
        function con3ValueChanged(app, event)
            value = app.con3.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(3).vertices);
            
            if value > 0
                app.Contact3Label.FontColor = [0.1 0.55 0.6];
                app.con3.FontColor = [0.1 0.55 0.6];
                app.con3_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(3),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con3.FontColor = [0.1 0.1 0.1];
                app.Contact3Label.FontColor = [0.1 0.1 0.1];
                app.con3_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(3),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: con2
        function con2ValueChanged(app, event)
            value = app.con2.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(2).vertices);
            
            if value > 0
                app.Contact2Label.FontColor = [0.1 0.55 0.6];
                app.con2.FontColor = [0.1 0.55 0.6];
                app.con2_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(2),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con2.FontColor = [0.1 0.1 0.1];
                app.Contact2Label.FontColor = [0.1 0.1 0.1];
                app.con2_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(2),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: con1
        function con1ValueChanged(app, event)
            value = app.con1.Value;
            dir_fastfield = app.fastfield_folder;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep value_el filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(1).vertices);
            
            if value > 0
                app.Contact1Label.FontColor = [0.1 0.55 0.6];
                app.con1.FontColor = [0.1 0.55 0.6];
                app.con1_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(1),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con1.FontColor = [0.1 0.1 0.1];
                app.Contact1Label.FontColor = [0.1 0.1 0.1];
                app.con1_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(1),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: amp_slider
        function amp_sliderValueChanged(app, event)
            value = app.amp_slider.Value;
            app.amp_box.Value = value;
            
            if ~isempty(app.electrode_type.Value)
                
                model_ff(app)
                visualize_ff(app)
            else
                app.error_box.Value = 'Please choose an electrode type';
            end
    

        end

        % Value changed function: amp_box
        function amp_boxValueChanged(app, event)
            
            value = app.amp_box.Value;
            app.amp_slider.Value = value;
            if ~isempty(app.electrode_type.Value)

                model_ff(app)
                visualize_ff(app)
            else
                app.error_box.Value = 'Please choose an electrode type';
      
            end

        end

        % Value changed function: cond_slider
        function cond_sliderValueChanged(app, event)
            value = app.cond_slider.Value;
            app.cond_box.Value = value;
            if ~isempty(app.electrode_type.Value)

                model_ff(app)
                visualize_ff(app)
            else
                app.error_box.Value = 'Please choose an electrode type';
            end

        end

        % Value changed function: cond_box
        function cond_boxValueChanged(app, event)
            value = app.cond_box.Value;
            app.cond_slider.Value = value;
            if ~isempty(app.electrode_type.Value)

                model_ff(app)
                visualize_ff(app)
            else
                app.error_box.Value = 'Please choose an electrode type';
            end
        end

        % Callback function
        function error_boxValueChanged(app, event)
            app.error_box.Editable = 'off';
            app.error_box.Enable = 'off';
            
        end

        % Value changed function: error_box
        function error_boxValueChanged2(app, event)
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            model_ff(app)
            visualize_ff(app)
            struc_brain = app.struc_brain1;
            dir_fastfield = app.fastfield_folder;
            if isempty(app.atl)
                app.atl = load([dir_fastfield filesep 'resources' filesep 'atlas_combined.mat']);
            end
            atlases = app.atl;
            
            for kk=1:length(struc_brain)
              if  struc_brain(kk) == 1
                  delete(app.hTarget(kk))
                  app.hTarget(kk) = NaN;
                  tar = atlases.labels{1, 1}(kk);
                  show_atlas(app,tar)
              end
            end
        end

        % Callback function
        function atlas_nameValueChanged(app, event)
            value = num2str(app.atlas_name.Value);
            if strcmp(value,'CIT168')
                app.uiFigSelect = AtlasCIT168;
                
            end
        end

        % Button pushed function: atlas_button
        function atlas_buttonButtonPushed(app, event)
            app.uiFigSelect = AtlasCIT168(app);
            struc_brain = app.struc_brain1;
            
            
            if  struc_brain(1) == 1
                app.uiFigSelect.CheckBox_Ca.Value = 1;
            end
            if  struc_brain(2) == 1
                app.uiFigSelect.CheckBox_EXA.Value = 1;
            end
            if  struc_brain(3) == 1
                app.uiFigSelect.CheckBox_GPe1.Value = 1;
            end
            if  struc_brain(4) == 1
                app.uiFigSelect.CheckBox_GPi1.Value = 1;
            end
            if  struc_brain(5) == 1
                app.uiFigSelect.CheckBox_HN.Value = 1;
            end
            if  struc_brain(6) == 1
                app.uiFigSelect.CheckBox_HTH.Value = 1;
            end
            if  struc_brain(7) == 1
                app.uiFigSelect.CheckBox_MN.Value = 1;
            end
            if  struc_brain(8) == 1
                app.uiFigSelect.CheckBox_NAC.Value = 1;
            end
            if  struc_brain(9) == 1
                app.uiFigSelect.CheckBox_PBP.Value = 1;
            end
            if  struc_brain(10) == 1
                app.uiFigSelect.CheckBox_Pu.Value = 1;
            end
            if  struc_brain(11) == 1
                app.uiFigSelect.CheckBox_RN1.Value = 1;
            end
            if  struc_brain(12) == 1
                app.uiFigSelect.CheckBox_SNc.Value = 1;
            end
            if  struc_brain(13) == 1
                app.uiFigSelect.CheckBox_SNr.Value = 1;
            end
            if  struc_brain(14) == 1
                app.uiFigSelect.CheckBox_STN1.Value = 1;
            end
            if  struc_brain(15) == 1
                app.uiFigSelect.CheckBox_VTA.Value = 1;
            end
            if  struc_brain(16) == 1
                app.uiFigSelect.CheckBox_VPe.Value = 1;
            end
            if  struc_brain(17) == 1
                app.uiFigSelect.CheckBox_STN2.Value = 1;
            end
            if  struc_brain(18) == 1
                app.uiFigSelect.CheckBox_STN2motor.Value = 1;
            end
            if  struc_brain(19) == 1
                app.uiFigSelect.CheckBox_STN2associative.Value = 1;
            end
            if  struc_brain(20) == 1
                app.uiFigSelect.CheckBox_STN2limbic.Value = 1;
            end
            if  struc_brain(21) == 1
                app.uiFigSelect.CheckBox_GPi2.Value = 1;
            end
            if  struc_brain(22) == 1
                app.uiFigSelect.CheckBox_GPisensorimotor.Value = 1;
            end
            if  struc_brain(23) == 1
                app.uiFigSelect.CheckBox_GPiprimarymotor.Value = 1;
            end
            if  struc_brain(24) == 1
                app.uiFigSelect.CheckBox_GPipremotor.Value = 1;
            end
            if  struc_brain(25) == 1
                app.uiFigSelect.CheckBox_GPisensory.Value = 1;
            end
            if  struc_brain(26) == 1
                app.uiFigSelect.CheckBox_GPipostparietal.Value = 1;
            end
            if  struc_brain(27) == 1
                app.uiFigSelect.CheckBox_GPioccipital.Value = 1;
            end
            if  struc_brain(28) == 1
                app.uiFigSelect.CheckBox_GPitemporal.Value = 1;
            end
            if  struc_brain(29) == 1
                app.uiFigSelect.CheckBox_GPiprefrontal.Value = 1;
            end
            if  struc_brain(30) == 1
                app.uiFigSelect.CheckBox_RN2.Value = 1;
            end
            if  struc_brain(31) == 1
                app.uiFigSelect.CheckBox_GPe2.Value = 1;
            end
            if  struc_brain(32) == 1
                app.uiFigSelect.CheckBox_Th.Value = 1;
            end
            if  struc_brain(33) == 1
                app.uiFigSelect.CheckBox_VA.Value = 1;
            end
            if  struc_brain(34) == 1
                app.uiFigSelect.CheckBox_VIM.Value = 1;
            end
            if  struc_brain(35) == 1
                app.uiFigSelect.CheckBox_VOICM.Value = 1;
            end
            
            if  struc_brain(36) == 1
                app.uiFigSelect.CheckBox_ALL.Value = 1;
            end
            
        end

        % Button pushed function: btnApproximateFromPW
        function onBtnApproximateFromPWClicked(app, event)
            f = approxonGui;
            uiwait(f); % setting thresh
            app.eFieldThreshold = getappdata(f, 'thresh');
            app.inputEFieldThreshold.Value = app.eFieldThreshold;
            close(f);
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: inputEFieldThreshold
        function onInputEFieldThresholdClicked(app, event)
            app.eFieldThreshold = app.inputEFieldThreshold.Value;
            model_ff(app)
            visualize_ff(app)
        end

        % Value changed function: LightSwitch
        function LightSwitchValueChanged(app, event)
            value = app.LightSwitch.Value;
            %if strcam(app.light,'on')
            
            switch value
                case '' % remove light
                    delete(app.camlightLeft);
                    delete(app.camlightRight);
                    delete(app.camlightHeadlight);
                    app.LightSwitch.FontColor = [0 0 0];
                    app.LightSwitch.FontWeight = 'Normal';
                    
                case 'On' %add light (or leave it like it is when its already on)
                    if(isempty(app.camlightLeft) || ~ishandle(app.camlightLeft))
                        app.camlightLeft = camlight(app.axes_result, 'left');
                            app.camlightRight = camlight(app.axes_result, 90, -25, 'infinite');
                        %app.camlightRight = camlight(app.axes_result, 90, -45, 'infinite');
                       %app.camlightHeadlight = camlight(app.axes_result, 90, 45, 'infinite');
                   
                    end
                    app.LightSwitch.FontColor = [0.1 0.9 0.15];
                    app.LightSwitch.FontWeight = 'Bold';
                    lighting(app.axes_result,'gouraud'); % put gouraud shading computations

            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            patient_dir = app.patient_folder;
            stim_n = app.stim_name.Value;
            stim_folder = [patient_dir filesep stim_n '.mat'];
            stimulation = app.stimulation1;
            stimulation.amp = app.amp_box.Value;
            stimulation.conductivity = app.cond_box.Value;
            stimulation.electrode_type = app.electrode_type.Value;
            stimulation.treshold = app.eFieldThreshold;
            xg = app.xg_handle;
            yg = app.yg_handle;
            zg = app.zg_handle;
            elfv = app.elfv_handle;
            Efield = app.efield_handle;
            trans_matrix = app.trans_mat_hanlde;
            save(stim_folder,'Efield','stimulation','elfv','xg','yg','zg','trans_matrix','-mat')
        end

        % Callback function
        function atlas_struValueChanged(app, event)
            app.atlas_stru.Multiselect = 'on';
            value = app.atlas_stru.Value;
            
        end

        % Callback function
        function ElectrodedesignPanelSizeChanged(app, event)
            position = app.ElectrodedesignPanel.Position;
            
        end

        % Callback function
        function ZoomButtonPushed(app, event)
            zoom(app.axes_result,'on');
        end

        % Callback function
        function RotationButtonPushed(app, event)
             rotate3d(app.axes_result,'on');
        end

        % Callback function
        function PanButtonPushed(app, event)
              pan(app.axes_result,'on');
        end

        % Value changed function: amp_mode
        function amp_modeValueChanged(app, event)
            value = app.amp_mode.Value;
            dir_patient = app.patient_folder;
            dir_fastfield = app.fastfield_folder;
            Electrode_type = app.electrode_type.Value;
            
            [standard_efield,grid_vec,electrode,electrode_patient,atlases] = load_files(dir_fastfield,dir_patient,Electrode_type);
            app.std_ef = standard_efield;
            app.grid_v = grid_vec;
            app.el_model = electrode;
            app.el_pat = electrode_patient;
            app.atl = atlases;
            model_ff(app)
            visualize_ff(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FastFieldUIFigure = uifigure('Visible', 'off');
            app.FastFieldUIFigure.Name = 'FastField';
            app.GridLayout3 = uigridlayout(app.FastFieldUIFigure);
            app.ViewPanel = uipanel(app.GridLayout3);
            app.ViewPanel.Title = 'View';
            app.GridLayout2 = uigridlayout(app.ViewPanel);
            app.axes_result = uiaxes(app.GridLayout2);
            app.axes_result.XTick = [];
            app.axes_result.YTick = [];
            app.axes_result.YTickLabel = '';
            app.axes_result.ZTick = [];
            app.Panel_4 = uipanel(app.GridLayout2);
            app.LightSwitchLabel = uilabel(app.Panel_4);
            app.LightSwitchLabel.Text = 'Light';
            app.LightSwitch = uiswitch(app.Panel_4, 'slider');
            app.LightSwitch.Items = {'', 'On'};
            app.LightSwitch.ValueChangedFcn = createCallbackFcn(app, @LightSwitchValueChanged, true);
            app.LightSwitch.Value = '';
            app.LightSwitchLabel_2 = uilabel(app.Panel_4);
            app.LightSwitchLabel_2.Text = 'Off';
            app.atlas_button = uibutton(app.GridLayout2, 'push');
            app.atlas_button.ButtonPushedFcn = createCallbackFcn(app, @atlas_buttonButtonPushed, true);
            app.atlas_button.Text = 'Brain Structures';
            app.MessagePanel = uipanel(app.GridLayout2);
            app.MessagePanel.Title = 'Message';
            app.error_box = uieditfield(app.MessagePanel, 'text');
            app.error_box.ValueChangedFcn = createCallbackFcn(app, @error_boxValueChanged2, true);
            app.ControlPanel = uipanel(app.GridLayout3);
            app.ControlPanel.Title = 'Control';
            app.GridLayout4 = uigridlayout(app.ControlPanel);
            app.ParametersPanel = uipanel(app.GridLayout4);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.ParametersPanel);
            app.amp_box = uieditfield(app.GridLayout, 'numeric');
            app.amp_box.ValueChangedFcn = createCallbackFcn(app, @amp_boxValueChanged, true);
            app.amp_box.Value = 1;
            app.cond_box = uieditfield(app.GridLayout, 'numeric');
            app.cond_box.ValueChangedFcn = createCallbackFcn(app, @cond_boxValueChanged, true);
            app.cond_box.Value = 0.1;
            app.SideDropDownLabel_3 = uilabel(app.GridLayout);
            app.SideDropDownLabel_3.Text = 'VTA Tresh. (V/mm)';
            app.btnApproximateFromPW = uibutton(app.GridLayout, 'push');
            app.btnApproximateFromPW.ButtonPushedFcn = createCallbackFcn(app, @onBtnApproximateFromPWClicked, true);
            app.btnApproximateFromPW.Text = 'Approximate from PW';
            app.inputEFieldThreshold = uieditfield(app.GridLayout, 'numeric');
            app.inputEFieldThreshold.ValueChangedFcn = createCallbackFcn(app, @onInputEFieldThresholdClicked, true);
            app.inputEFieldThreshold.Value = 0.2;
            app.ElectrodedesignPanel = uipanel(app.GridLayout);
            app.ElectrodedesignPanel.Title = 'Electrode design';
            app.axes_el = uiaxes(app.ElectrodedesignPanel);
            title(app.axes_el, '')
            app.axes_el.XTick = [];
            app.axes_el.YTick = [];
            app.ContacSPanel = uipanel(app.GridLayout);
            app.ContacSPanel.Title = 'Contacs energy';
            app.con8_per = uilabel(app.ContacSPanel);
            app.con8_per.Text = '%';
            app.Contact8Label = uilabel(app.ContacSPanel);
            app.Contact8Label.Text = 'Contact 8';
            app.con8 = uieditfield(app.ContacSPanel, 'numeric');
            app.con8.ValueChangedFcn = createCallbackFcn(app, @con8ValueChanged, true);
            app.Contact7Label = uilabel(app.ContacSPanel);
            app.Contact7Label.Text = 'Contact 7';
            app.con7 = uieditfield(app.ContacSPanel, 'numeric');
            app.con7.ValueChangedFcn = createCallbackFcn(app, @con7ValueChanged, true);
            app.Contact6Label = uilabel(app.ContacSPanel);
            app.Contact6Label.Text = 'Contact 6';
            app.con6 = uieditfield(app.ContacSPanel, 'numeric');
            app.con6.ValueChangedFcn = createCallbackFcn(app, @con6ValueChanged, true);
            app.Contact5Label = uilabel(app.ContacSPanel);
            app.Contact5Label.Text = 'Contact 5';
            app.con5 = uieditfield(app.ContacSPanel, 'numeric');
            app.con5.ValueChangedFcn = createCallbackFcn(app, @con5ValueChanged, true);
            app.Contact4Label = uilabel(app.ContacSPanel);
            app.Contact4Label.Text = 'Contact 4';
            app.con4 = uieditfield(app.ContacSPanel, 'numeric');
            app.con4.ValueChangedFcn = createCallbackFcn(app, @con4ValueChanged, true);
            app.Contact3Label = uilabel(app.ContacSPanel);
            app.Contact3Label.Text = 'Contact 3';
            app.con3 = uieditfield(app.ContacSPanel, 'numeric');
            app.con3.ValueChangedFcn = createCallbackFcn(app, @con3ValueChanged, true);
            app.Contact2Label = uilabel(app.ContacSPanel);
            app.Contact2Label.Text = 'Contact 2';
            app.con2 = uieditfield(app.ContacSPanel, 'numeric');
            app.con2.ValueChangedFcn = createCallbackFcn(app, @con2ValueChanged, true);
            app.Contact1Label = uilabel(app.ContacSPanel);
            app.Contact1Label.Text = 'Contact 1';
            app.con1 = uieditfield(app.ContacSPanel, 'numeric');
            app.con1.ValueChangedFcn = createCallbackFcn(app, @con1ValueChanged, true);
            app.con1.Value = 100;
            app.con7_per = uilabel(app.ContacSPanel);
            app.con7_per.Text = '%';
            app.con6_per = uilabel(app.ContacSPanel);
            app.con6_per.Text = '%';
            app.con5_per = uilabel(app.ContacSPanel);
            app.con5_per.Text = '%';
            app.con4_per = uilabel(app.ContacSPanel);
            app.con4_per.Text = '%';
            app.con3_per = uilabel(app.ContacSPanel);
            app.con3_per.Text = '%';
            app.con2_per = uilabel(app.ContacSPanel);
            app.con2_per.Text = '%';
            app.con1_per = uilabel(app.ContacSPanel);
            app.con1_per.Text = '%';
            app.AmplitudemASliderLabel = uilabel(app.GridLayout);
            app.AmplitudemASliderLabel.Text = 'Amplitude (mA)';
            app.amp_slider = uislider(app.GridLayout);
            app.amp_slider.Limits = [0 10];
            app.amp_slider.MajorTicks = [];
            app.amp_slider.ValueChangedFcn = createCallbackFcn(app, @amp_sliderValueChanged, true);
            app.amp_slider.MinorTicks = [];
            app.amp_slider.Value = 1;
            app.ConductivitySmSliderLabel = uilabel(app.GridLayout);
            app.ConductivitySmSliderLabel.Text = 'Conductivity (S/m)';
            app.cond_slider = uislider(app.GridLayout);
            app.cond_slider.Limits = [0.01 0.9];
            app.cond_slider.MajorTicks = [];
            app.cond_slider.ValueChangedFcn = createCallbackFcn(app, @cond_sliderValueChanged, true);
            app.cond_slider.MinorTicks = [];
            app.cond_slider.Value = 0.16;
            app.ElectrodeTypeLabel = uilabel(app.GridLayout);
            app.ElectrodeTypeLabel.Text = 'Electrode Type';
            app.electrode_type = uidropdown(app.GridLayout);
            app.electrode_type.Items = {'boston_vercise_directed', 'boston_vercise', 'medtronic_3389', 'medtronic_3387', 'medtronic_3391', 'stjude_directed_short', 'stjude_directed_long', 'stjude_activetip_2mm', 'stjude_activetip_3mm', 'pins_l301', 'pins_l302', 'pins_l303', ''};
            app.electrode_type.ValueChangedFcn = createCallbackFcn(app, @electrode_typeValueChanged, true);
            app.electrode_type.Value = 'boston_vercise_directed';
            app.SideDropDownLabel_5 = uilabel(app.GridLayout);
            app.SideDropDownLabel_5.Text = 'Amplitude mode';
            app.amp_mode = uiswitch(app.GridLayout, 'slider');
            app.amp_mode.Items = {'mA', 'V'};
            app.amp_mode.ValueChangedFcn = createCallbackFcn(app, @amp_modeValueChanged, true);
            app.amp_mode.Value = 'mA';
            app.PersonalisetopatientsanatomyPanel = uipanel(app.GridLayout4);
            app.PersonalisetopatientsanatomyPanel.Title = 'Personalise to patient''s anatomy';
            app.patient_path = uibutton(app.PersonalisetopatientsanatomyPanel, 'push');
            app.patient_path.ButtonPushedFcn = createCallbackFcn(app, @patient_pathButtonPushed, true);
            app.patient_path.Text = 'Choose Leadbs Patient Directory';
            app.stim_name = uieditfield(app.PersonalisetopatientsanatomyPanel, 'text');
            app.stim_name.Value = 'Stim1';
            app.ButtonGroup = uibuttongroup(app.PersonalisetopatientsanatomyPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.right_side = uiradiobutton(app.ButtonGroup);
            app.right_side.Text = 'Right';
            app.right_side.Value = true;
            app.left_side = uiradiobutton(app.ButtonGroup);
            app.left_side.Text = 'Left';
            app.SideDropDownLabel_2 = uilabel(app.PersonalisetopatientsanatomyPanel);
            app.SideDropDownLabel_2.Text = 'Side';
            app.SideDropDownLabel_4 = uilabel(app.PersonalisetopatientsanatomyPanel);
            app.SideDropDownLabel_4.Text = 'Stimulation name';
            app.SaveButton = uibutton(app.PersonalisetopatientsanatomyPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.FastFieldUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FastField(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FastFieldUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FastFieldUIFigure)
        end
    end
end